classdef version1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ImportButton                    matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        AddnewtableButton               matlab.ui.control.Button
        CalculationsOptionalButton      matlab.ui.control.Button
        ReplicatesaverageMandatoryButton  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        RowsEditFieldLabel              matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel           matlab.ui.control.Label
        ColumnsEditField                matlab.ui.control.NumericEditField
        Button_3                        matlab.ui.control.Button
        DensityLabel                    matlab.ui.control.Label
        AreaColumnEditFieldLabel        matlab.ui.control.Label
        AreaColumnEditField             matlab.ui.control.NumericEditField
        CellcountColumnEditFieldLabel   matlab.ui.control.Label
        CellcountColumnEditField        matlab.ui.control.NumericEditField
        ProportionLabel                 matlab.ui.control.Label
        TotalpopulationcolumnEditFieldLabel  matlab.ui.control.Label
        TotalpopulationcolumnEditField  matlab.ui.control.NumericEditField
        SubpopulationcolumnEditFieldLabel  matlab.ui.control.Label
        SubpopulationcolumnEditField    matlab.ui.control.NumericEditField
        NumberofreplicatespersampleSpinnerLabel  matlab.ui.control.Label
        NumberofreplicatespersampleSpinner  matlab.ui.control.Spinner
        ColumntodotheaverageEditFieldLabel  matlab.ui.control.Label
        ColumntodotheaverageEditField   matlab.ui.control.NumericEditField
        Button_6                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        AddrowButton                    matlab.ui.control.Button
        AddcolumnButton                 matlab.ui.control.Button
        DeleterowButton                 matlab.ui.control.Button
        DeleteColumnButton              matlab.ui.control.Button
        ResetTableButton_5              matlab.ui.control.Button
        ResetProjectButton              matlab.ui.control.Button
        MainTableLabel                  matlab.ui.control.Label
        NewTables                       matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        ExportButton_2                  matlab.ui.control.Button
        ExportButton_3                  matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        BasicstatisticstableLabel       matlab.ui.control.Label
        ReplicatestableLabel            matlab.ui.control.Label
        DefineGroupsButton_2            matlab.ui.control.Button
        Button_18                       matlab.ui.control.Button
        NumberofsamplepergroupEditFieldLabel  matlab.ui.control.Label
        NumberofsamplepergroupEditField  matlab.ui.control.NumericEditField
        NumberofgroupsEditFieldLabel    matlab.ui.control.Label
        NumberofgroupsEditField         matlab.ui.control.NumericEditField
        Button_19                       matlab.ui.control.Button
        Button_21                       matlab.ui.control.Button
        ResetTableButton                matlab.ui.control.Button
        ResetTableButton_2              matlab.ui.control.Button
        Button_28                       matlab.ui.control.Button
        Button_29                       matlab.ui.control.Button
        GraphsTab                       matlab.ui.container.Tab
        UITable4                        matlab.ui.control.Table
        GraphstableLabel                matlab.ui.control.Label
        Button_15                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        BoxButton                       matlab.ui.control.Button
        BarButton                       matlab.ui.control.Button
        ImportButton_3                  matlab.ui.control.Button
        AutoButton                      matlab.ui.control.Button
        Button_17                       matlab.ui.control.Button
        ExportButton_4                  matlab.ui.control.Button
        ColumnforthegraphEditFieldLabel  matlab.ui.control.Label
        ColumnforthegraphEditField      matlab.ui.control.NumericEditField
        Button_22                       matlab.ui.control.Button
        Button_24                       matlab.ui.control.Button
        Button_25                       matlab.ui.control.Button
        BarwithSEMCheckBox              matlab.ui.control.CheckBox
        ResetTableButton_3              matlab.ui.control.Button
        StatisticsTab                   matlab.ui.container.Tab
        waysANOVAMultiplecomparisonButton  matlab.ui.control.Button
        WelchsTTestButton               matlab.ui.control.Button
        UITable5                        matlab.ui.control.Table
        ColumnforthestatisticsEditFieldLabel  matlab.ui.control.Label
        ColumnforthestatisticsEditField  matlab.ui.control.NumericEditField
        NumberofsamplesofthefirstgroupinthetableEditFieldLabel  matlab.ui.control.Label
        NumberofsamplesofthefirstgroupinthetableEditField  matlab.ui.control.NumericEditField
        StatisticstableLabel            matlab.ui.control.Label
        ResetTableButton_4              matlab.ui.control.Button
        Button_26                       matlab.ui.control.Button
        Button_27                       matlab.ui.control.Button
        ImageprocessingTab              matlab.ui.container.Tab
        WORKINLabel                     matlab.ui.control.Label
        PROGRESSLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
           [name,path]= uigetfile('*xlsx','import an excel');
           if name==0
               return;
           else
               data=strcat(path,name);
               app.UITable.Data=readtable(data);
              app.UITable.ColumnName=app.UITable.Data.Properties.VariableNames;
               app.UITable.ColumnEditable=true;
           end
        end

        % Button pushed function: CalculationsOptionalButton
        function CalculationsOptionalButtonPushed(app, event)
            choice2=questdlg('Which one','Selection','Density','Proportion','Other','Density');

        if isequal(choice2,'Density')
        
        area=app.AreaColumnEditField.Value;
        area_array=table2array(app.UITable.Data(:,area));
        cell=app.CellcountColumnEditField.Value;
        cell_array=table2array(app.UITable.Data(:,cell));
        choice3=questdlg('In which unit is your area','unit of your area','um^2','mm^2','m^2','um^2');
        if isequal(choice3,'um^2')
        Density=(cell_array./(area_array./10^6));
        app.UITable.Data=addvars(app.UITable.Data,Density,'After',width(app.UITable.Data), 'NewVariableNames',{'Density'})
        app.UITable.ColumnName=app.UITable.Data.Properties.VariableNames
        elseif isequal(choice3,'mm^2')
        Density=(cell_array./(area_array));
        app.UITable.Data=addvars(app.UITable.Data,Density,'After',width(app.UITable.Data),'NewVariableNames',{'Density'})
        app.UITable.ColumnName=app.UITable.Data.Properties.VariableNames
        elseif isequal(choice3,'m^2')
        Density=(cell_array./(area_array.*10^6));
        app.UITable.Data=addvars(app.UITable.Data,Density,'After',width(app.UITable.Data),'NewVariableNames',{'Density'})
        app.UITable.ColumnName=app.UITable.Data.Properties.VariableNames
        else
        msgbox('Change in your excel for one of this units')
        end
 
    elseif isequal(choice2,'Proportion')
       
         total=app.TotalpopulationcolumnEditField.Value;
         total_array=table2array(app.UITable.Data(:,total));
         prop=app.SubpopulationcolumnEditField.Value;
         prop_array=table2array(app.UITable.Data(:,prop));
         Proportion=(prop_array./total_array).*100;
         app.UITable.Data=addvars(app.UITable.Data,Proportion,'After',width(app.UITable.Data),'NewVariableNames',{'Proportion'})
         app.UITable.ColumnName=app.UITable.Data.Properties.VariableNames
    elseif isequal(choice2,'Other')
        msgbox('WORK IN PROGRESS')
        end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
          uiwait(msgbox({'-Fill the boxes below according with the calculation you want to do.';'-Density will be calculated as cell/mm^2.';'-Proportion will be calculated as %.'}))
         
        end

        % Value changed function: AreaColumnEditField
        function AreaColumnEditFieldValueChanged(app, event)
            value = app.AreaColumnEditField.Value;
            
        end

        % Value changed function: CellcountColumnEditField
        function CellcountColumnEditFieldValueChanged(app, event)
            value = app.CellcountColumnEditField.Value;
            
        end

        % Callback function
        function Button_4Pushed(app, event)
        
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            msgbox('Fill with the desired column number of the main table')
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
             msgbox('Fill with the desired column number of the main table')
        end

        % Callback function
        function Button_5Pushed(app, event)
             msgbox('Fill with the desired column number of the main table')
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            msgbox('Fill with the desired column number of the main table')
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
             msgbox('Fill with the desired column number of the main table')
        end

        % Value changed function: TotalpopulationcolumnEditField
        function TotalpopulationcolumnEditFieldValueChanged(app, event)
            value = app.TotalpopulationcolumnEditField.Value;
            
        end

        % Value changed function: SubpopulationcolumnEditField
        function SubpopulationcolumnEditFieldValueChanged(app, event)
            value = app.SubpopulationcolumnEditField.Value;
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [name2,path2] = uiputfile('*.xlsx','Save table');
            fullfilepath=fullfile(path2,name2)
            writetable(app.UITable.Data,fullfilepath)
        end

        % Button pushed function: AddnewtableButton
        function AddnewtableButtonPushed(app, event)
            set(app.UITable, 'Data', [])
            set(app.UITable, 'ColumnName',{}) 
            app.UITable.Data = zeros(app.RowsEditField.Value,app.ColumnsEditField.Value)
            app.UITable.ColumnEditable=true
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            value = app.RowsEditField.Value;
            
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            value = app.ColumnsEditField.Value;
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            msgbox('First, specify the number of rows and columns.')
        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            app.UITable.Data(end+1,:) = 0; 
        app.UITable.ColumnEditable=true
        end

        % Button pushed function: AddcolumnButton
        function AddcolumnButtonPushed(app, event)
        app.UITable.Data(:,end+1) = 0;
        app.UITable.ColumnEditable=true
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            app.UITable.Data(end,:) = [];
        end

        % Button pushed function: DeleteColumnButton
        function DeleteColumnButtonPushed(app, event)
            app.UITable.Data(:,end) = [];
        end

        % Button pushed function: ReplicatesaverageMandatoryButton
        function ReplicatesaverageMandatoryButtonPushed(app, event)
    t=istable(app.UITable2.Data) 
    if t==1
    app.UITable2.Data=table2array(app.UITable2.Data)
    end
    msgbox('Results will appear in the tab "new tables"')
    w=app.NumberofreplicatespersampleSpinner.Value
    trasp=1:(height(app.UITable.Data)/w)
    app.UITable2.Data(:,1)=[trasp']
    app.UITable2.ColumnName(1)={'Sample'}
    if app.NumberofreplicatespersampleSpinner.Value ==2
    replicates_vector=zeros((height(app.UITable.Data)/2),1);
    n=1;
    m=2;
    column_to_analyse=app.ColumntodotheaverageEditField.Value;
    for i=1:(height(app.UITable.Data)/2)
    average_replicates=(table2array(app.UITable.Data(n,column_to_analyse))+table2array(app.UITable.Data(m,column_to_analyse)))/2;
    n=n+app.NumberofreplicatespersampleSpinner.Value;
    m=m+app.NumberofreplicatespersampleSpinner.Value;
    replicates_vector(i)=average_replicates;
    end
    app.UITable2.Data=[app.UITable2.Data replicates_vector]
    column_name=char(inputdlg('column name'));
    app.UITable2.ColumnName{end+1}=column_name;

    end
    
if app.NumberofreplicatespersampleSpinner.Value ==3
    replicates_vector=zeros((height(app.UITable.Data)/3),1);
    n=1;
    m=2;
    m2=3
    column_to_analyse=app.ColumntodotheaverageEditField.Value;
    for i=1:(height(app.UITable.Data)/3)
    average_replicates=(table2array(app.UITable.Data(n,column_to_analyse))+table2array(app.UITable.Data(m,column_to_analyse))+table2array(app.UITable.Data(m2,column_to_analyse)))/3;
    n=n+app.NumberofreplicatespersampleSpinner.Value;
    m=m+app.NumberofreplicatespersampleSpinner.Value;
    m2=m2+app.NumberofreplicatespersampleSpinner.Value;
    replicates_vector(i)=average_replicates;
    end
    app.UITable2.Data=[app.UITable2.Data replicates_vector]
    column_name=char(inputdlg('column name'));
    app.UITable2.ColumnName{end+1}=column_name;
end
if app.NumberofreplicatespersampleSpinner.Value ==1
    column_to_analyse=app.ColumntodotheaverageEditField.Value
    trasp=1:height(app.UITable.Data)
    replicates_vector = table2array(app.UITable.Data(:,column_to_analyse));
    app.UITable2.Data=[app.UITable2.Data replicates_vector]
    column_name=char(inputdlg('column name'));
    app.UITable2.ColumnName{end+1}=column_name;
    
end

        end

        % Value changed function: NumberofreplicatespersampleSpinner
        function NumberofreplicatespersampleSpinnerValueChanged(app, event)
            value = app.NumberofreplicatespersampleSpinner.Value;
            
        end

        % Value changed function: ColumntodotheaverageEditField
        function ColumntodotheaverageEditFieldValueChanged(app, event)
            value = app.ColumntodotheaverageEditField.Value;
            
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            uiwait(msgbox({'-Replicates button will fill "Replicates table" (in New tables tab).';'-It has to be done even if there are no replicated data for posterior analysis (max. 3 replicates).'}))
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            msgbox('Fill with the desired column number of the main table')
        end

        % Callback function
        function Button_7Pushed(app, event)


        end

        % Button pushed function: DefineGroupsButton_2
        function DefineGroupsButton_2Pushed(app, event)
        t=istable(app.UITable2.Data) 
    if t==1
    app.UITable2.Data=table2array(app.UITable2.Data)
    end
        decision=char(inputdlg('Do all groups have the same number of samples? (Yes=Y;No=N in capital letter)'))
        if isequal (decision,'N')
        msgbox('Assign the same numerical value (example 1,2,3 etc) to the samples of the same group (manually in the "Group code" column)')
        w=app.NumberofreplicatespersampleSpinner.Value
        new_column=zeros(height(app.UITable.Data)/w,1)
        app.UITable2.Data=[app.UITable2.Data new_column]
        app.UITable2.Data=array2table(app.UITable2.Data)
        app.UITable2.ColumnName(end+1)={'Group code'}
        app.UITable2.ColumnEditable=true
       else
w1=app.NumberofreplicatespersampleSpinner.Value
new_column=zeros(height(app.UITable.Data)/w1,1)
start=1;
finish=0;
            for i2=1:app.NumberofgroupsEditField.Value
            n=app.NumberofsamplepergroupEditField.Value;
            finish_mod=finish+n;
            new_column(start:finish_mod)=i2;
            start=start+n;
            finish=finish+n;
            end
   app.UITable2.Data=[app.UITable2.Data new_column]
   app.UITable2.Data=array2table(app.UITable2.Data)
   app.UITable2.ColumnEditable=true
   app.UITable2.ColumnName(end+1)={'Group code'}
       end
      

        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            msgbox('If number of sample is the same for all groups, they will be assigned automatically')
        end

        % Value changed function: NumberofsamplepergroupEditField
        function NumberofsamplepergroupEditFieldValueChanged(app, event)
            value = app.NumberofsamplepergroupEditField.Value;
            
        end

        % Value changed function: NumberofgroupsEditField
        function NumberofgroupsEditFieldValueChanged(app, event)
            value = app.NumberofgroupsEditField.Value;
            
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            msgbox('Fill only if all groups have the same number of sample.')
        end

        % Callback function
        function DeleteColumnButton_2Pushed(app, event)
          app.UITable2.Data(:,end)= [];
          
        end

        % Button pushed function: ExportButton_3
        function ExportButton_3Pushed(app, event)
            [name3,path3] = uiputfile('*.xlsx','Save table');
            fullfilepath=fullfile(path3,name3);
            writematrix(app.UITable2.Data,fullfilepath);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             t=istable(app.UITable2.Data);
    if t==0
    app.UITable2.Data=arrat2table(app.UITable2.Data);
    end
            n=app.NumberofsamplepergroupEditField.Value
            j=size(app.UITable2.Data)
            j2=j(2)-1
            data3=table2array(app.UITable2.Data)
            mean=splitapply(@mean,data3(:,2:j2),data3(:,end))
            std=splitapply(@std,data3(:,2:j2),data3(:,end))
            var=splitapply(@var,data3(:,2:j2),data3(:,end))
            SEM=std./(sqrt(n))
            app.UITable3.Data=[mean std var SEM]
            j3=j(2)-2
            j4=j3+1
            j5=(j(2)-2)*2
            j6=j5+1
            j7=(j(2)-2)*3
            j8=j7+1
            j9=(j(2)-2)*4
            app.UITable3.ColumnName(1:j3)={'mean'}
            app.UITable3.ColumnName(j4:j5)={'Standard Desviation'}
            app.UITable3.ColumnName(j6:j7)={'Variance'}
            app.UITable3.ColumnName(j8:j9)={'SEM'}
        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            uiwait(msgbox({'-This will calculate mean, standard deviation, variance and SEM of the different groups.';'-"Define groups" will be requested first for this calculation.'}))
            
        end

        % Cell edit callback: UITable4
        function UITable4CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
          n1=app.NumberofsamplepergroupEditField.Value
            gr=app.NumberofgroupsEditField.Value
            app.UITable4.Data=zeros(n1,gr);
        c1=app.ColumnforthegraphEditField.Value;
        z0=1;
        
        for i3=1:gr
            for i4=1:n1
            app.UITable4.Data(i4,i3)=table2array(app.UITable2.Data(z0,c1));
            z0=z0+1;
            end
        end 
         app.UITable4.ColumnEditable=true
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            msgbox('Only if all the groups have the same samples. Ff not, export "replicates table" and organize the groups in columns (then, "import" in this table)')
        end

        % Value changed function: ColumnforthegraphEditField
        function ColumnforthegraphEditFieldValueChanged(app, event)
            value = app.ColumnforthegraphEditField.Value;
            
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            msgbox('Choose a column from replicates table')
        end

        % Callback function
        function Button_16Pushed(app, event)
            msgbox('Export the data that you generate in repeated measures, organized in excel each group in different columns only of the variable to analyze and import it again here')
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            msgbox('Groups must be organized in different columns. For missing values, write "NaN"')
        end

        % Button pushed function: BoxButton
        function BoxButtonPushed(app, event)
       reset(app.UIAxes)
            box_table=app.UITable4.Data
            boxplot(app.UIAxes,box_table) 
        end

        % Callback function
        function Button_23Pushed(app, event)
    set(app.UITable2, 'Data', [])
    set(app.UITable2, 'ColumnName',{})
    
        end

        % Button pushed function: BarButton
        function BarButtonPushed(app, event)
      cb1=app.BarwithSEMCheckBox.Value
       j=size(app.UITable2.Data)
       j2=j(2)-1
       j3=j(2)-2
       j4=j3+1
       j5=(j(2)-2)*2
       j6=j5+1
       j7=(j(2)-2)*3
       j8=j7+1
       j9=(j(2)-2)*4
       
       tmean=app.UITable3.Data(:,1:j3)
       tstd=app.UITable3.Data(:,j4:j5)
            
       tsem=app.UITable3.Data(:,j8:j9)
      if cb1==0
      reset(app.UIAxes)
      gr1=app.NumberofgroupsEditField.Value
      x1=1:gr1;
      column1=app.ColumnforthegraphEditField.Value
      v2=column1-1;
      
   bar(app.UIAxes,x1,tmean(:,v2))
   hold (app.UIAxes,'on')
   er=errorbar(app.UIAxes,x1,tmean(:,v2),tstd(:,v2),tstd(:,v2));
   er.Color=[0 0 0];
   er.LineStyle = 'none';
      else
   reset(app.UIAxes)
   gr1=app.NumberofgroupsEditField.Value
   x1=1:gr1;
   column1=app.ColumnforthegraphEditField.Value
   v2=column1-1;
   bar(app.UIAxes,x1,tmean(:,v2))
   hold (app.UIAxes,'on')
   er=errorbar(app.UIAxes,x1,tmean(:,v2),tsem(:,v2),tsem(:,v2));
   er.Color=[0 0 0];
   er.LineStyle = 'none';
      end
  
        end

        % Value changed function: BarwithSEMCheckBox
        function BarwithSEMCheckBoxValueChanged(app, event)
            value = app.BarwithSEMCheckBox.Value;
            
        end

        % Button pushed function: ImportButton_3
        function ImportButton_3Pushed(app, event)
            [name3,path3]= uigetfile('*xlsx','import an excel');
           if name3==0
               return;
           else
               data=strcat(path3,name3);
               app.UITable4.Data=readtable(data);
               app.UITable4.Data=table2array(app.UITable4.Data)
               app.UITable4.ColumnEditable=true;
           end
        end

        % Button pushed function: ExportButton_4
        function ExportButton_4Pushed(app, event)
            [name5,path5] = uiputfile('*.xlsx','Save table');
            fullfilepath=fullfile(path5,name5)
            writematrix(app.UITable4.Data,fullfilepath)
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            msgbox('Box takes data from "Graphs table". If you "import" data, make sure to write NaN if there are any missing values')
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            msgbox('Bar takes data from "Basic statistics table"')
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
  
            [name6,path6] = uiputfile('*.xlsx','Save table');
            fullfilepath=fullfile(path6,name6)
            writematrix(app.UITable3.Data,fullfilepath)
        end

        % Cell edit callback: UITable5
        function UITable5CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: waysANOVAMultiplecomparisonButton
        function waysANOVAMultiplecomparisonButtonPushed(app, event)
         set(app.UITable5, 'Data', [])
    set(app.UITable5, 'ColumnName',{})
            t=istable(app.UITable2.Data) 
           if t==1
          app.UITable2.Data=table2array(app.UITable2.Data)
           end
            gr2=app.NumberofgroupsEditField.Value
            n1=app.NumberofsamplepergroupEditField.Value;
    ANOVA2_matrix=zeros(n1,gr2);
        c1=app.ColumnforthestatisticsEditField.Value;
        z0=1;
       
        for i3=1:gr2
            for i4=1:n1
            ANOVA2_matrix(i4,i3)=app.UITable2.Data(z0,c1);
            z0=z0+1;
            end 
        end
        
    
  [~,~,stats]=anova2(ANOVA2_matrix,1);
 statistics=multcompare(stats);
app.UITable5.Data=array2table(statistics,"VariableNames",["Group","Group to compare","Lower Limit","difference","Upper Limit","P-value"])
app.UITable5.ColumnName=app.UITable5.Data.Properties.VariableNames
        
        end

        % Value changed function: ColumnforthestatisticsEditField
        function ColumnforthestatisticsEditFieldValueChanged(app, event)
            value = app.ColumnforthestatisticsEditField.Value;
            
        end

        % Button pushed function: WelchsTTestButton
        function WelchsTTestButtonPushed(app, event)
             t=istable(app.UITable2.Data) 
    if t==1
    app.UITable2.Data=table2array(app.UITable2.Data)
    end
    c2=app.ColumnforthestatisticsEditField.Value;
    n_group1=app.NumberofsamplesofthefirstgroupinthetableEditField.Value
   [h,p]=ttest2(app.UITable2.Data(1:n_group1,c2),app.UITable2.Data(n_group1+1:end,c2),'Vartype','unequal')
   app.UITable5.Data=p
   app.UITable5.ColumnName={'P-value'}
        end

        % Value changed function: 
        % NumberofsamplesofthefirstgroupinthetableEditField
        function NumberofsamplesofthefirstgroupinthetableEditFieldValueChanged(app, event)
            value = app.NumberofsamplesofthefirstgroupinthetableEditField.Value;
            
        end

        % Button pushed function: ResetTableButton_5
        function ResetTableButton_5Pushed(app, event)
    set(app.UITable, 'Data', [])
    set(app.UITable, 'ColumnName',{})
        end

        % Button pushed function: ResetTableButton
        function ResetTableButtonPushed(app, event)
    set(app.UITable2, 'Data', [])
    set(app.UITable2, 'ColumnName',{})
        end

        % Button pushed function: ResetTableButton_2
        function ResetTableButton_2Pushed(app, event)
            set(app.UITable3, 'Data', [])
    set(app.UITable3, 'ColumnName',{})
        end

        % Button pushed function: ResetTableButton_3
        function ResetTableButton_3Pushed(app, event)
            set(app.UITable4, 'Data', [])
    set(app.UITable4, 'ColumnName',{})
        end

        % Button pushed function: ResetTableButton_4
        function ResetTableButton_4Pushed(app, event)
    set(app.UITable5, 'Data', [])
    set(app.UITable5, 'ColumnName',{})
        end

        % Button pushed function: ResetProjectButton
        function ResetProjectButtonPushed(app, event)
    
    set(app.UITable, 'Data', [])
    set(app.UITable, 'ColumnName',{})
    
    set(app.UITable2, 'Data', [])
    set(app.UITable2, 'ColumnName',{})
    
    set(app.UITable3, 'Data', [])
    set(app.UITable3, 'ColumnName',{})
    
    set(app.UITable4, 'Data', [])
    set(app.UITable4, 'ColumnName',{})
    
    set(app.UITable5, 'Data', [])
    set(app.UITable5, 'ColumnName',{})
    
    reset(app.UIAxes)
    box_table=app.UITable4.Data
    boxplot(app.UIAxes,box_table)
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
             msgbox('Choose a column from replicates table')
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
            msgbox('Mandatory')
        end

        % Button pushed function: Button_28
        function Button_28Pushed(app, event)
            msgbox('"Number of groups" and "number of sample per groups" boxes will be necessary for some graphs and statistics')
        end

        % Button pushed function: Button_29
        function Button_29Pushed(app, event)
                 uiwait(msgbox({'-stats appear in the same order than in "Replicates table.';'-Each row represent one group (in the order of the group code'}))
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ImportButton = uibutton(app.DataTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.DataTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.AddnewtableButton = uibutton(app.DataTab, 'push');
            app.AddnewtableButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewtableButtonPushed, true);
            app.AddnewtableButton.Text = 'Add new table';
            app.CalculationsOptionalButton = uibutton(app.DataTab, 'push');
            app.CalculationsOptionalButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationsOptionalButtonPushed, true);
            app.CalculationsOptionalButton.Text = 'Calculations (Optional)';
            app.ReplicatesaverageMandatoryButton = uibutton(app.DataTab, 'push');
            app.ReplicatesaverageMandatoryButton.ButtonPushedFcn = createCallbackFcn(app, @ReplicatesaverageMandatoryButtonPushed, true);
            app.ReplicatesaverageMandatoryButton.Text = 'Replicates'' average(Mandatory)';
            app.Button = uibutton(app.DataTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.DataTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.RowsEditFieldLabel = uilabel(app.DataTab);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.DataTab, 'numeric');
            app.RowsEditField.Limits = [1 100];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Value = 1;
            app.ColumnsEditFieldLabel = uilabel(app.DataTab);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.DataTab, 'numeric');
            app.ColumnsEditField.Limits = [1 100];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Value = 1;
            app.Button_3 = uibutton(app.DataTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.DensityLabel = uilabel(app.DataTab);
            app.DensityLabel.Text = 'Density';
            app.AreaColumnEditFieldLabel = uilabel(app.DataTab);
            app.AreaColumnEditFieldLabel.Text = 'Area Column ';
            app.AreaColumnEditField = uieditfield(app.DataTab, 'numeric');
            app.AreaColumnEditField.ValueChangedFcn = createCallbackFcn(app, @AreaColumnEditFieldValueChanged, true);
            app.CellcountColumnEditFieldLabel = uilabel(app.DataTab);
            app.CellcountColumnEditFieldLabel.Text = 'Cell count Column';
            app.CellcountColumnEditField = uieditfield(app.DataTab, 'numeric');
            app.CellcountColumnEditField.ValueChangedFcn = createCallbackFcn(app, @CellcountColumnEditFieldValueChanged, true);
            app.ProportionLabel = uilabel(app.DataTab);
            app.ProportionLabel.Text = 'Proportion';
            app.TotalpopulationcolumnEditFieldLabel = uilabel(app.DataTab);
            app.TotalpopulationcolumnEditFieldLabel.Text = 'Total population column';
            app.TotalpopulationcolumnEditField = uieditfield(app.DataTab, 'numeric');
            app.TotalpopulationcolumnEditField.ValueChangedFcn = createCallbackFcn(app, @TotalpopulationcolumnEditFieldValueChanged, true);
            app.SubpopulationcolumnEditFieldLabel = uilabel(app.DataTab);
            app.SubpopulationcolumnEditFieldLabel.Text = 'Subpopulation column';
            app.SubpopulationcolumnEditField = uieditfield(app.DataTab, 'numeric');
            app.SubpopulationcolumnEditField.ValueChangedFcn = createCallbackFcn(app, @SubpopulationcolumnEditFieldValueChanged, true);
            app.NumberofreplicatespersampleSpinnerLabel = uilabel(app.DataTab);
            app.NumberofreplicatespersampleSpinnerLabel.Text = 'Number of replicates per sample';
            app.NumberofreplicatespersampleSpinner = uispinner(app.DataTab);
            app.NumberofreplicatespersampleSpinner.Limits = [1 3];
            app.NumberofreplicatespersampleSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofreplicatespersampleSpinnerValueChanged, true);
            app.NumberofreplicatespersampleSpinner.Value = 1;
            app.ColumntodotheaverageEditFieldLabel = uilabel(app.DataTab);
            app.ColumntodotheaverageEditFieldLabel.Text = 'Column to do the average';
            app.ColumntodotheaverageEditField = uieditfield(app.DataTab, 'numeric');
            app.ColumntodotheaverageEditField.ValueChangedFcn = createCallbackFcn(app, @ColumntodotheaverageEditFieldValueChanged, true);
            app.ColumntodotheaverageEditField.Value = 2;
            app.Button_6 = uibutton(app.DataTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '?';
            app.Button_8 = uibutton(app.DataTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '?';
            app.Button_9 = uibutton(app.DataTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '?';
            app.Button_10 = uibutton(app.DataTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '?';
            app.Button_11 = uibutton(app.DataTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '?';
            app.AddrowButton = uibutton(app.DataTab, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = 'Add row';
            app.AddcolumnButton = uibutton(app.DataTab, 'push');
            app.AddcolumnButton.ButtonPushedFcn = createCallbackFcn(app, @AddcolumnButtonPushed, true);
            app.AddcolumnButton.Text = 'Add column';
            app.DeleterowButton = uibutton(app.DataTab, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = 'Delete row';
            app.DeleteColumnButton = uibutton(app.DataTab, 'push');
            app.DeleteColumnButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteColumnButtonPushed, true);
            app.DeleteColumnButton.Text = 'Delete Column';
            app.ResetTableButton_5 = uibutton(app.DataTab, 'push');
            app.ResetTableButton_5.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButton_5Pushed, true);
            app.ResetTableButton_5.Text = 'Reset Table';
            app.ResetProjectButton = uibutton(app.DataTab, 'push');
            app.ResetProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ResetProjectButtonPushed, true);
            app.ResetProjectButton.Text = 'Reset Project';
            app.MainTableLabel = uilabel(app.DataTab);
            app.MainTableLabel.Text = 'Main Table';
            app.NewTables = uitab(app.TabGroup);
            app.NewTables.Title = 'New Tables';
            app.UITable2 = uitable(app.NewTables);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.UITable3 = uitable(app.NewTables);
            app.UITable3.ColumnName = {''};
            app.UITable3.RowName = {};
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.ExportButton_2 = uibutton(app.NewTables, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.ExportButton_3 = uibutton(app.NewTables, 'push');
            app.ExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_3Pushed, true);
            app.ExportButton_3.Text = 'Export';
            app.CalculateButton = uibutton(app.NewTables, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.BasicstatisticstableLabel = uilabel(app.NewTables);
            app.BasicstatisticstableLabel.Text = 'Basic statistics table';
            app.ReplicatestableLabel = uilabel(app.NewTables);
            app.ReplicatestableLabel.Text = 'Replicates table';
            app.DefineGroupsButton_2 = uibutton(app.NewTables, 'push');
            app.DefineGroupsButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefineGroupsButton_2Pushed, true);
            app.DefineGroupsButton_2.Text = 'Define Groups';
            app.Button_18 = uibutton(app.NewTables, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '?';
            app.NumberofsamplepergroupEditFieldLabel = uilabel(app.NewTables);
            app.NumberofsamplepergroupEditFieldLabel.Text = 'Number of sample per group';
            app.NumberofsamplepergroupEditField = uieditfield(app.NewTables, 'numeric');
            app.NumberofsamplepergroupEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsamplepergroupEditFieldValueChanged, true);
            app.NumberofgroupsEditFieldLabel = uilabel(app.NewTables);
            app.NumberofgroupsEditFieldLabel.Text = 'Number of groups';
            app.NumberofgroupsEditField = uieditfield(app.NewTables, 'numeric');
            app.NumberofgroupsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofgroupsEditFieldValueChanged, true);
            app.Button_19 = uibutton(app.NewTables, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '?';
            app.Button_21 = uibutton(app.NewTables, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '?';
            app.ResetTableButton = uibutton(app.NewTables, 'push');
            app.ResetTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButtonPushed, true);
            app.ResetTableButton.Text = 'Reset Table';
            app.ResetTableButton_2 = uibutton(app.NewTables, 'push');
            app.ResetTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButton_2Pushed, true);
            app.ResetTableButton_2.Text = 'Reset Table';
            app.Button_28 = uibutton(app.NewTables, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_28Pushed, true);
            app.Button_28.Text = '?';
            app.Button_29 = uibutton(app.NewTables, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Button_29Pushed, true);
            app.Button_29.Text = '?';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UITable4 = uitable(app.GraphsTab);
            app.UITable4.ColumnName = {''};
            app.UITable4.RowName = {};
            app.UITable4.CellEditCallback = createCallbackFcn(app, @UITable4CellEdit, true);
            app.GraphstableLabel = uilabel(app.GraphsTab);
            app.GraphstableLabel.Text = 'Graphs table';
            app.Button_15 = uibutton(app.GraphsTab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '?';
            app.UIAxes = uiaxes(app.GraphsTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BoxButton = uibutton(app.GraphsTab, 'push');
            app.BoxButton.ButtonPushedFcn = createCallbackFcn(app, @BoxButtonPushed, true);
            app.BoxButton.Text = 'Box';
            app.BarButton = uibutton(app.GraphsTab, 'push');
            app.BarButton.ButtonPushedFcn = createCallbackFcn(app, @BarButtonPushed, true);
            app.BarButton.Text = 'Bar';
            app.ImportButton_3 = uibutton(app.GraphsTab, 'push');
            app.ImportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_3Pushed, true);
            app.ImportButton_3.Text = 'Import';
            app.AutoButton = uibutton(app.GraphsTab, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.Button_17 = uibutton(app.GraphsTab, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '?';
            app.ExportButton_4 = uibutton(app.GraphsTab, 'push');
            app.ExportButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_4Pushed, true);
            app.ExportButton_4.Text = 'Export';
            app.ColumnforthegraphEditFieldLabel = uilabel(app.GraphsTab);
            app.ColumnforthegraphEditFieldLabel.Text = 'Column for the graph';
            app.ColumnforthegraphEditField = uieditfield(app.GraphsTab, 'numeric');
            app.ColumnforthegraphEditField.Limits = [2 Inf];
            app.ColumnforthegraphEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnforthegraphEditFieldValueChanged, true);
            app.ColumnforthegraphEditField.Value = 2;
            app.Button_22 = uibutton(app.GraphsTab, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '?';
            app.Button_24 = uibutton(app.GraphsTab, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '?';
            app.Button_25 = uibutton(app.GraphsTab, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '?';
            app.BarwithSEMCheckBox = uicheckbox(app.GraphsTab);
            app.BarwithSEMCheckBox.ValueChangedFcn = createCallbackFcn(app, @BarwithSEMCheckBoxValueChanged, true);
            app.BarwithSEMCheckBox.Text = 'Bar with SEM';
            app.ResetTableButton_3 = uibutton(app.GraphsTab, 'push');
            app.ResetTableButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButton_3Pushed, true);
            app.ResetTableButton_3.Text = 'Reset Table';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.waysANOVAMultiplecomparisonButton = uibutton(app.StatisticsTab, 'push');
            app.waysANOVAMultiplecomparisonButton.ButtonPushedFcn = createCallbackFcn(app, @waysANOVAMultiplecomparisonButtonPushed, true);
            app.waysANOVAMultiplecomparisonButton.Text = '2-ways ANOVA: Multiple comparison';
            app.WelchsTTestButton = uibutton(app.StatisticsTab, 'push');
            app.WelchsTTestButton.ButtonPushedFcn = createCallbackFcn(app, @WelchsTTestButtonPushed, true);
            app.WelchsTTestButton.Text = 'Welch''s T-Test';
            app.UITable5 = uitable(app.StatisticsTab);
            app.UITable5.ColumnName = {''};
            app.UITable5.RowName = {};
            app.UITable5.CellEditCallback = createCallbackFcn(app, @UITable5CellEdit, true);
            app.ColumnforthestatisticsEditFieldLabel = uilabel(app.StatisticsTab);
            app.ColumnforthestatisticsEditFieldLabel.Text = 'Column for the statistics';
            app.ColumnforthestatisticsEditField = uieditfield(app.StatisticsTab, 'numeric');
            app.ColumnforthestatisticsEditField.Limits = [2 Inf];
            app.ColumnforthestatisticsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnforthestatisticsEditFieldValueChanged, true);
            app.ColumnforthestatisticsEditField.Value = 2;
            app.NumberofsamplesofthefirstgroupinthetableEditFieldLabel = uilabel(app.StatisticsTab);
            app.NumberofsamplesofthefirstgroupinthetableEditFieldLabel.Text = 'Number of samples of the first group in the table';
            app.NumberofsamplesofthefirstgroupinthetableEditField = uieditfield(app.StatisticsTab, 'numeric');
            app.NumberofsamplesofthefirstgroupinthetableEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsamplesofthefirstgroupinthetableEditFieldValueChanged, true);
            app.StatisticstableLabel = uilabel(app.StatisticsTab);
            app.StatisticstableLabel.Text = 'Statistics table';
            app.ResetTableButton_4 = uibutton(app.StatisticsTab, 'push');
            app.ResetTableButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButton_4Pushed, true);
            app.ResetTableButton_4.Text = 'Reset Table';
            app.Button_26 = uibutton(app.StatisticsTab, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Text = '?';
            app.Button_27 = uibutton(app.StatisticsTab, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Text = '?';
            app.ImageprocessingTab = uitab(app.TabGroup);
            app.ImageprocessingTab.Title = 'Image processing';
            app.WORKINLabel = uilabel(app.ImageprocessingTab);
            app.WORKINLabel.Text = 'WORK IN';
            app.PROGRESSLabel = uilabel(app.ImageprocessingTab);
            app.PROGRESSLabel.Text = 'PROGRESS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = version1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end